classdef Assignment1app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Base2XEditFieldLabel          matlab.ui.control.Label
        Base2X                        matlab.ui.control.NumericEditField
        Base2YEditFieldLabel          matlab.ui.control.Label
        Base2Y                        matlab.ui.control.NumericEditField
        RUNButton                     matlab.ui.control.Button
        BoxXLabel                     matlab.ui.control.Label
        BoxXEditField                 matlab.ui.control.NumericEditField
        BoxYEditFieldLabel            matlab.ui.control.Label
        BoxYEditField                 matlab.ui.control.NumericEditField
        ClearButton                   matlab.ui.control.Button
        Base1XEditFieldLabel          matlab.ui.control.Label
        Base1X                        matlab.ui.control.NumericEditField
        Base1YEditFieldLabel          matlab.ui.control.Label
        Base1Y                        matlab.ui.control.NumericEditField
        ModelPanel                    matlab.ui.container.Panel
        Linear1GraphicalLabel         matlab.ui.control.Label
        Model                         matlab.ui.control.DropDown
        CenterPanel                   matlab.ui.container.Panel
        HousingTopXEditFieldLabel     matlab.ui.control.Label
        p1X                           matlab.ui.control.NumericEditField
        HousingTopYEditFieldLabel     matlab.ui.control.Label
        p1Y                           matlab.ui.control.NumericEditField
        HousingBottomXEditFieldLabel  matlab.ui.control.Label
        p3X                           matlab.ui.control.NumericEditField
        HousingBottomYEditFieldLabel  matlab.ui.control.Label
        p3Y                           matlab.ui.control.NumericEditField
        PCBXEditFieldLabel            matlab.ui.control.Label
        p2X                           matlab.ui.control.NumericEditField
        PCBYEditFieldLabel            matlab.ui.control.Label
        p2Y                           matlab.ui.control.NumericEditField
        MovementSpeedKnobLabel        matlab.ui.control.Label
        StepsFast                     matlab.ui.control.Knob
        ApproximationSpeedKnobLabel   matlab.ui.control.Label
        StepsSlow                     matlab.ui.control.Knob
        RightPanel                    matlab.ui.container.Panel
        PathsPanel                    matlab.ui.container.Panel
        Prequisite1bagLabel           matlab.ui.control.Label
        Model2                        matlab.ui.control.DropDown
        RUNPATHButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Base1X
        function Base1XValueChanged(app, event)
            value = app.Base1X.Value;
            
        end

        % Value changed function: Base1Y
        function Base1YValueChanged(app, event)
            value = app.Base1Y.Value;
           
        end

        % Value changed function: Base2X
        function Base2XValueChanged(app, event)
            value = app.Base2X.Value;
            
        end

        % Value changed function: Base2Y
        function Base2YValueChanged(app, event)
            value = app.Base2Y.Value;

        end

        % Value changed function: p1X
        function p1XValueChanged(app, event)
            value = app.p1X.Value;
            
        end

        % Value changed function: p1Y
        function p1YValueChanged(app, event)
            value = app.p1Y.Value;

        end

        % Value changed function: p2X
        function p2XValueChanged(app, event)
            value = app.p2X.Value;
            
        end

        % Value changed function: p2Y
        function p2YValueChanged(app, event)
            value = app.p2Y.Value;
         
        end

        % Value changed function: p3X
        function p3XValueChanged(app, event)
            value = app.p3X.Value;
            
        end

        % Value changed function: p3Y
        function p3YValueChanged(app, event)
            value = app.p3Y.Value;
 
        end

        % Value changed function: BoxXEditField
        function BoxXEditFieldValueChanged(app, event)
            value = app.BoxXEditField.Value;
            
        end

        % Value changed function: BoxYEditField
        function BoxYEditFieldValueChanged(app, event)
            value = app.BoxYEditField.Value;
            
        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            value = app.Model.Value;
           
        end

        % Value changed function: StepsFast
        function StepsFastValueChanged(app, event)
            value = app.StepsFast.Value;
            
        end

        % Value changed function: StepsSlow
        function StepsSlowValueChanged(app, event)
            value = app.StepsSlow.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            model = app.Model.Value;
            base1 = [app.Base1X.Value,app.Base1Y.Value,0];
            base2 = [app.Base2X.Value,app.Base2Y.Value,0];
            p1 = [app.p1X.Value,app.p1Y.Value,0.04];
            p2 = [app.p2X.Value,app.p2Y.Value,0];
            p3 = [app.p3X.Value,app.p3Y.Value,0];
            box = [app.BoxXEditField.Value,app.BoxYEditField.Value,0];
            stepsFast = app.StepsFast.Value;
            stepsSlow = app.StepsSlow.Value;
            
            Program(model,base1,base2,p1,p2,p3,box,stepsFast,stepsSlow)

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            ClearFunction()
        end

        % Value changed function: Model2
        function Model2ValueChanged(app, event)
            value = app.Model2.Value;
            
        end

        % Button pushed function: RUNPATHButton
        function RUNPATHButtonPushed(app, event)
            model = app.Model.Value;
            base1 = [app.Base1X.Value,app.Base1Y.Value,0];
            base2 = [app.Base2X.Value,app.Base2Y.Value,0];
            p1 = [app.p1X.Value,app.p1Y.Value,0.04];
            p2 = [app.p2X.Value,app.p2Y.Value,0];
            p3 = [app.p3X.Value,app.p3Y.Value,0];
            box = [app.BoxXEditField.Value,app.BoxYEditField.Value,0];
            stepsFast = app.StepsFast.Value;
            stepsSlow = app.StepsSlow.Value;
            path = app.Model2.Value;
            
            ProgramPaths(model,base1,base2,p1,p2,p3,box,stepsFast,stepsSlow,path)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {387, 387, 387};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {387, 387};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x', 233};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Base2XEditFieldLabel = uilabel(app.LeftPanel);
            app.Base2XEditFieldLabel.Text = 'Base 2 - X';
            app.Base2X = uieditfield(app.LeftPanel, 'numeric');
            app.Base2X.ValueChangedFcn = createCallbackFcn(app, @Base2XValueChanged, true);
            app.Base2X.Value = 0.23;
            app.Base2YEditFieldLabel = uilabel(app.LeftPanel);
            app.Base2YEditFieldLabel.Text = 'Base 2 - Y';
            app.Base2Y = uieditfield(app.LeftPanel, 'numeric');
            app.Base2Y.ValueChangedFcn = createCallbackFcn(app, @Base2YValueChanged, true);
            app.Base2Y.Value = 0.23;
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.BoxXLabel = uilabel(app.LeftPanel);
            app.BoxXLabel.Text = 'Box - X';
            app.BoxXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BoxXEditField.ValueChangedFcn = createCallbackFcn(app, @BoxXEditFieldValueChanged, true);
            app.BoxYEditFieldLabel = uilabel(app.LeftPanel);
            app.BoxYEditFieldLabel.Text = 'Box - Y';
            app.BoxYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BoxYEditField.ValueChangedFcn = createCallbackFcn(app, @BoxYEditFieldValueChanged, true);
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Base1XEditFieldLabel = uilabel(app.LeftPanel);
            app.Base1XEditFieldLabel.Text = 'Base 1 - X';
            app.Base1X = uieditfield(app.LeftPanel, 'numeric');
            app.Base1X.ValueChangedFcn = createCallbackFcn(app, @Base1XValueChanged, true);
            app.Base1X.Value = -0.23;
            app.Base1YEditFieldLabel = uilabel(app.LeftPanel);
            app.Base1YEditFieldLabel.Text = 'Base 1 - Y';
            app.Base1Y = uieditfield(app.LeftPanel, 'numeric');
            app.Base1Y.ValueChangedFcn = createCallbackFcn(app, @Base1YValueChanged, true);
            app.Base1Y.Value = 0.23;
            app.ModelPanel = uipanel(app.LeftPanel);
            app.ModelPanel.Title = 'Model';
            app.Linear1GraphicalLabel = uilabel(app.ModelPanel);
            app.Linear1GraphicalLabel.Text = {'0 - Linear'; '1 - Graphical'; ''};
            app.Model = uidropdown(app.ModelPanel);
            app.Model.Items = {'0', '1', ''};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = '1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.HousingTopXEditFieldLabel = uilabel(app.CenterPanel);
            app.HousingTopXEditFieldLabel.Text = 'Housing Top - X';
            app.p1X = uieditfield(app.CenterPanel, 'numeric');
            app.p1X.ValueChangedFcn = createCallbackFcn(app, @p1XValueChanged, true);
            app.p1X.Value = -0.42;
            app.HousingTopYEditFieldLabel = uilabel(app.CenterPanel);
            app.HousingTopYEditFieldLabel.Text = 'Housing Top - Y';
            app.p1Y = uieditfield(app.CenterPanel, 'numeric');
            app.p1Y.ValueChangedFcn = createCallbackFcn(app, @p1YValueChanged, true);
            app.p1Y.Value = 0.46;
            app.HousingBottomXEditFieldLabel = uilabel(app.CenterPanel);
            app.HousingBottomXEditFieldLabel.Text = 'Housing Bottom - X';
            app.p3X = uieditfield(app.CenterPanel, 'numeric');
            app.p3X.ValueChangedFcn = createCallbackFcn(app, @p3XValueChanged, true);
            app.p3X.Value = 0.42;
            app.HousingBottomYEditFieldLabel = uilabel(app.CenterPanel);
            app.HousingBottomYEditFieldLabel.Text = 'Housing Bottom - Y';
            app.p3Y = uieditfield(app.CenterPanel, 'numeric');
            app.p3Y.ValueChangedFcn = createCallbackFcn(app, @p3YValueChanged, true);
            app.p3Y.Value = 0.46;
            app.PCBXEditFieldLabel = uilabel(app.CenterPanel);
            app.PCBXEditFieldLabel.Text = 'PCB - X';
            app.p2X = uieditfield(app.CenterPanel, 'numeric');
            app.p2X.ValueChangedFcn = createCallbackFcn(app, @p2XValueChanged, true);
            app.p2X.Value = 0.22;
            app.PCBYEditFieldLabel = uilabel(app.CenterPanel);
            app.PCBYEditFieldLabel.Text = 'PCB - Y';
            app.p2Y = uieditfield(app.CenterPanel, 'numeric');
            app.p2Y.ValueChangedFcn = createCallbackFcn(app, @p2YValueChanged, true);
            app.p2Y.Value = -0.1;
            app.MovementSpeedKnobLabel = uilabel(app.CenterPanel);
            app.MovementSpeedKnobLabel.Text = 'Movement Speed';
            app.StepsFast = uiknob(app.CenterPanel, 'continuous');
            app.StepsFast.MajorTicks = [1 10 25 35 50 75 100];
            app.StepsFast.ValueChangedFcn = createCallbackFcn(app, @StepsFastValueChanged, true);
            app.StepsFast.MinorTicks = [5 15 20 30 40 45 55 60 65 70 80 85 90 95];
            app.StepsFast.Value = 35;
            app.ApproximationSpeedKnobLabel = uilabel(app.CenterPanel);
            app.ApproximationSpeedKnobLabel.Text = 'Approximation Speed';
            app.StepsSlow = uiknob(app.CenterPanel, 'continuous');
            app.StepsSlow.MajorTicks = [1 10 25 35 50 75 100];
            app.StepsSlow.ValueChangedFcn = createCallbackFcn(app, @StepsSlowValueChanged, true);
            app.StepsSlow.MinorTicks = [5 15 20 30 40 45 55 60 65 70 80 85 90 95];
            app.StepsSlow.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.PathsPanel = uipanel(app.RightPanel);
            app.PathsPanel.Title = 'Paths';
            app.Prequisite1bagLabel = uilabel(app.PathsPanel);
            app.Prequisite1bagLabel.Text = {'0 - Prequisite'; '1 - .bag'; ''};
            app.Model2 = uidropdown(app.PathsPanel);
            app.Model2.Items = {'0', '1', ''};
            app.Model2.ValueChangedFcn = createCallbackFcn(app, @Model2ValueChanged, true);
            app.Model2.Value = '0';
            app.RUNPATHButton = uibutton(app.RightPanel, 'push');
            app.RUNPATHButton.ButtonPushedFcn = createCallbackFcn(app, @RUNPATHButtonPushed, true);
            app.RUNPATHButton.Text = 'RUN PATH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment1app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end